classdef app_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CameraStatusSwitchLabel         matlab.ui.control.Label
        CameraStatusSwitch              matlab.ui.control.Switch
        ImagesourceLabel                matlab.ui.control.Label
        ImagesourceDropDown             matlab.ui.control.DropDown
        GetimagefileButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GrayimageTab                    matlab.ui.container.Tab
        ChromaTab                       matlab.ui.container.Tab
        SelectedcolorLamp               matlab.ui.control.Lamp
        Threhold1SliderLabel            matlab.ui.control.Label
        Threhold1Slider                 matlab.ui.control.Slider
        Threhold2SliderLabel            matlab.ui.control.Label
        Threhold2Slider                 matlab.ui.control.Slider
        PickacolorButton                matlab.ui.control.Button
        t1Label                         matlab.ui.control.Label
        t2Label                         matlab.ui.control.Label
        GaussianradiusSpinnerLabel      matlab.ui.control.Label
        GaussianradiusSpinner           matlab.ui.control.Spinner
        HumandetectionTab               matlab.ui.container.Tab
        DetectiontypeDropDownLabel      matlab.ui.control.Label
        DetectiontypeDropDown           matlab.ui.control.DropDown
        BackgroundpercentageSliderLabel  matlab.ui.control.Label
        BackgroundpercentageSlider      matlab.ui.control.Slider
        SubdivisionrateSliderLabel      matlab.ui.control.Label
        SubdivisionrateSlider           matlab.ui.control.Slider
        GaussianradiusSpinner_2Label    matlab.ui.control.Label
        GaussianradiusSpinner_humandet  matlab.ui.control.Spinner
        ShowdetectionButton             matlab.ui.control.StateButton
        ApplyprocessingSwitchLabel      matlab.ui.control.Label
        ApplyprocessingSwitch           matlab.ui.control.Switch
        GetbackgroundimagefileButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxesBg                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        uiaxes_orig_position;
        
        cam; % camera controller
        control_obj;
        
        thr1_old_value;
        thr2_old_value;
    end
    
    methods (Access = public)
        
        function display_image(app, img)
            rel = size(img,1)/size(img,2);
            app.UIAxes.Position = [app.uiaxes_orig_position(1) app.uiaxes_orig_position(2) app.uiaxes_orig_position(3) app.uiaxes_orig_position(3)*rel];
            
            imshow(img, 'Parent', app.UIAxes, 'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            drawnow;
        end
        
        function display_bg(app, img)
            imshow(img, 'Parent', app.UIAxesBg, 'XData', [1 app.UIAxesBg.Position(3)], 'YData', [1 app.UIAxesBg.Position(4)]);
            drawnow;
        end
        
        function disable_webcam(app)
            app.CameraStatusSwitch.Visible = false;
            app.CameraStatusSwitchLabel.Visible = false;
        end
        
        function enable_webcam(app)
            app.CameraStatusSwitch.Visible = true;
            app.CameraStatusSwitchLabel.Visible = true;
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, control_obj)
            app.control_obj = control_obj;
            
            app.cam = camera_handler("winvideo");
            
            % handlers
            app.CameraStatusSwitch.ValueChangedFcn = @(src, event) app.control_obj.change_camera_status();
            app.ImagesourceDropDown.ValueChangedFcn = @(src, event) app.control_obj.change_image_source();
            app.GetimagefileButton.ButtonPushedFcn = @(src, event) app.control_obj.get_image_file();
            app.GetbackgroundimagefileButton.ButtonPushedFcn = @(src, event) app.control_obj.get_bg_file();
            app.ApplyprocessingSwitch.ValueChangedFcn = @(src, event) app.control_obj.apply_processing();
            app.UIFigure.CloseRequestFcn = @(src, event) app.control_obj.close_request();
            
            app.TabGroup.SelectionChangedFcn = @(src, event) app.control_obj.select_method();
            
            
            % chroma parameters handlers
            app.PickacolorButton.ButtonPushedFcn = @(src, event) app.control_obj.pick_color();
            app.Threhold1Slider.ValueChangedFcn = @(src, event) app.control_obj.update_chroma_parameters();
            app.Threhold2Slider.ValueChangedFcn = @(src, event) app.control_obj.update_chroma_parameters();
            app.GaussianradiusSpinner.ValueChangedFcn = @(src, event) app.control_obj.update_chroma_parameters();
            
            % human detection handlers  update_human_detection_parameters
            app.GaussianradiusSpinner_humandet.ValueChangedFcn = @(src, event) app.control_obj.update_human_detection_parameters();
            app.SubdivisionrateSlider.ValueChangedFcn = @(src, event) app.control_obj.update_human_detection_parameters();
            app.BackgroundpercentageSlider.ValueChangedFcn = @(src, event) app.control_obj.update_human_detection_parameters();
            app.DetectiontypeDropDown.ValueChangedFcn = @(src, event) app.control_obj.update_human_detection_parameters();
            app.ShowdetectionButton.ValueChangedFcn = @(src, event) app.control_obj.update_human_detection_parameters();
            
            % setup
            items_app = app.ImagesourceDropDown.Items;
            app.ImagesourceDropDown.Value = items_app(1);
            app.disable_webcam();
            
            app.t1Label.Text = num2str(app.Threhold1Slider.Value, "%0.4f");
            app.t2Label.Text = num2str(app.Threhold2Slider.Value, "%0.4f");
            
            app.GaussianradiusSpinner.Limits = [0, +inf];
            app.GaussianradiusSpinner.Value = 2;
            
            app.GaussianradiusSpinner_humandet.Limits = [0, +inf];
            app.GaussianradiusSpinner_humandet.Value = 0;
            
            app.thr1_old_value = app.Threhold1Slider.Value;
            app.thr2_old_value = app.Threhold2Slider.Value;
            
            app.uiaxes_orig_position = app.UIAxes.Position;
        end

        % Value changing function: Threhold2Slider
        function Threhold2SliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue < app.Threhold1Slider.Value % t2 < t1
                app.Threhold1Slider.Value = app.thr2_old_value;
                drawnow;
                pause(0.1);
                return;
            end
            
            app.t2Label.Text = num2str(changingValue, "%0.4f");
            
            app.thr2_old_value = changingValue;
        end

        % Value changing function: Threhold1Slider
        function Threhold1SliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > app.Threhold2Slider.Value % t1 > t2
                app.Threhold2Slider.Value = app.thr1_old_value;
                drawnow;
                pause(0.1);
                return;
            end
            
            app.t1Label.Text = num2str(changingValue, "%0.4f");
            
            app.thr1_old_value = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameraStatusSwitchLabel = uilabel(app.UIFigure);
            app.CameraStatusSwitchLabel.Visible = 'off';
            app.CameraStatusSwitchLabel.Text = 'Camera Status';
            app.CameraStatusSwitch = uiswitch(app.UIFigure, 'slider');
            app.CameraStatusSwitch.Visible = 'off';
            app.ImagesourceLabel = uilabel(app.UIFigure);
            app.ImagesourceLabel.Text = 'Image source';
            app.ImagesourceDropDown = uidropdown(app.UIFigure);
            app.ImagesourceDropDown.Items = {'Image', 'Webcam'};
            app.ImagesourceDropDown.Value = 'Image';
            app.GetimagefileButton = uibutton(app.UIFigure, 'push');
            app.GetimagefileButton.Text = 'Get image file';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrayimageTab = uitab(app.TabGroup);
            app.GrayimageTab.Title = 'Gray image';
            app.ChromaTab = uitab(app.TabGroup);
            app.ChromaTab.Title = 'Chroma';
            app.SelectedcolorLamp = uilamp(app.ChromaTab);
            app.Threhold1SliderLabel = uilabel(app.ChromaTab);
            app.Threhold1SliderLabel.Text = 'Threhold 1';
            app.Threhold1Slider = uislider(app.ChromaTab);
            app.Threhold1Slider.Limits = [0 0.2];
            app.Threhold1Slider.ValueChangingFcn = createCallbackFcn(app, @Threhold1SliderValueChanging, true);
            app.Threhold2SliderLabel = uilabel(app.ChromaTab);
            app.Threhold2SliderLabel.Text = 'Threhold 2';
            app.Threhold2Slider = uislider(app.ChromaTab);
            app.Threhold2Slider.Limits = [0 0.5];
            app.Threhold2Slider.ValueChangingFcn = createCallbackFcn(app, @Threhold2SliderValueChanging, true);
            app.Threhold2Slider.Value = 0.01;
            app.PickacolorButton = uibutton(app.ChromaTab, 'push');
            app.PickacolorButton.Text = 'Pick a color';
            app.t1Label = uilabel(app.ChromaTab);
            app.t1Label.Text = 't1label';
            app.t2Label = uilabel(app.ChromaTab);
            app.t2Label.Text = 't2label';
            app.GaussianradiusSpinnerLabel = uilabel(app.ChromaTab);
            app.GaussianradiusSpinnerLabel.Text = 'Gaussian radius';
            app.GaussianradiusSpinner = uispinner(app.ChromaTab);
            app.GaussianradiusSpinner.Limits = [0 1];
            app.GaussianradiusSpinner.Value = 1;
            app.HumandetectionTab = uitab(app.TabGroup);
            app.HumandetectionTab.Title = 'Human detection';
            app.DetectiontypeDropDownLabel = uilabel(app.HumandetectionTab);
            app.DetectiontypeDropDownLabel.Text = 'Detection type';
            app.DetectiontypeDropDown = uidropdown(app.HumandetectionTab);
            app.DetectiontypeDropDown.Items = {'Face', 'Upper body'};
            app.DetectiontypeDropDown.Value = 'Face';
            app.BackgroundpercentageSliderLabel = uilabel(app.HumandetectionTab);
            app.BackgroundpercentageSliderLabel.Text = 'Background percentage';
            app.BackgroundpercentageSlider = uislider(app.HumandetectionTab);
            app.BackgroundpercentageSlider.Limits = [0.01 1];
            app.BackgroundpercentageSlider.MajorTicks = [0.01 0.3 0.6 1];
            app.BackgroundpercentageSlider.Value = 0.25;
            app.SubdivisionrateSliderLabel = uilabel(app.HumandetectionTab);
            app.SubdivisionrateSliderLabel.Text = 'Subdivision rate';
            app.SubdivisionrateSlider = uislider(app.HumandetectionTab);
            app.SubdivisionrateSlider.Limits = [0 500];
            app.SubdivisionrateSlider.Value = 200;
            app.GaussianradiusSpinner_2Label = uilabel(app.HumandetectionTab);
            app.GaussianradiusSpinner_2Label.Text = 'Gaussian radius';
            app.GaussianradiusSpinner_humandet = uispinner(app.HumandetectionTab);
            app.GaussianradiusSpinner_humandet.Limits = [0 1];
            app.GaussianradiusSpinner_humandet.Value = 1;
            app.ShowdetectionButton = uibutton(app.HumandetectionTab, 'state');
            app.ShowdetectionButton.Text = 'Show detection';
            app.ApplyprocessingSwitchLabel = uilabel(app.UIFigure);
            app.ApplyprocessingSwitchLabel.Text = 'Apply processing';
            app.ApplyprocessingSwitch = uiswitch(app.UIFigure, 'slider');
            app.GetbackgroundimagefileButton = uibutton(app.UIFigure, 'push');
            app.GetbackgroundimagefileButton.Text = 'Get background image file';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxesBg = uiaxes(app.UIFigure);
            title(app.UIAxesBg, 'Background')
            app.UIAxesBg.XTick = [];
            app.UIAxesBg.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end